classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        image                    matlab.ui.control.UIAxes
        NoiseRemovalSwitchLabel  matlab.ui.control.Label
        NoiseRemovalSwitch       matlab.ui.control.Switch
        OpenImageButton          matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        DeskewingSwitchLabel     matlab.ui.control.Label
        DeskewingSwitch          matlab.ui.control.Switch
    end

    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            clc;
          
            [name,canc]=imgetfile();
           
            if canc==0
            i=imread(name);
            imshow(i,'Parent',app.image);
            
            val=app.NoiseRemovalSwitch.Value;
            
            if strcmp(val,'On')==1
            i=Noise(i);
            else
                i=Binarize(i);
            end
            val=app.DeskewingSwitch.Value;
            if strcmp(val,'On')==1
                i=imdeskew(i);
            end
            imshow(i,'Parent',app.image);
            l=liness(i);
            name=[name(1:end-3) 'txt'];
            fID=fopen(name,'wt');
            wordss(l,fID);
            fclose(fID);
            set(app.Label,'Text','.txt file created');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.image = uiaxes(app.UIFigure);
            title(app.image, 'Image')
            app.NoiseRemovalSwitchLabel = uilabel(app.UIFigure);
            app.NoiseRemovalSwitchLabel.Text = 'Noise Removal';
            app.NoiseRemovalSwitch = uiswitch(app.UIFigure, 'slider');
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DeskewingSwitchLabel = uilabel(app.UIFigure);
            app.DeskewingSwitchLabel.Text = 'Deskewing';
            app.DeskewingSwitch = uiswitch(app.UIFigure, 'slider');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end